classdef Lithium_Pipeline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Browse_Button_DCI2NII_Folder  matlab.ui.control.Button
        Pathwaytodicm2niifolderEditField  matlab.ui.control.EditField
        Pathwaytodicm2niifolderEditFieldLabel  matlab.ui.control.Label
        BrowseButton_Output           matlab.ui.control.Button
        Browse_Button_FS_Folder       matlab.ui.control.Button
        Browse_Button_Lithium_T1w     matlab.ui.control.Button
        Browse_Button_Lithium_Img     matlab.ui.control.Button
        Browse_Button_Patient_List    matlab.ui.control.Button
        QuestionsandhelppleaseemailgerardhallnewcastleacukLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Hyperlink                     matlab.ui.control.Hyperlink
        PatientListtxtEditField       matlab.ui.control.EditField
        NoLithiumT1AcquiredCheckBox   matlab.ui.control.CheckBox
        OutputFolderEditField         matlab.ui.control.EditField
        OutputFolderEditFieldLabel    matlab.ui.control.Label
        RunPipelineButton             matlab.ui.control.Button
        FSFoldertxtEditField          matlab.ui.control.EditField
        FSFoldertxtEditFieldLabel     matlab.ui.control.Label
        LithiumT1wtxtEditField        matlab.ui.control.EditField
        LithiumT1wtxtEditFieldLabel   matlab.ui.control.Label
        FilepathsLabel                matlab.ui.control.Label
        LithiumImgtxtEditField        matlab.ui.control.EditField
        LithiumImgtxtEditFieldLabel   matlab.ui.control.Label
        LithiumAnalyzerLabel          matlab.ui.control.Label
        PatientListtxtEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Browse_Button_Patient_List
        function Browse_Button_Patient_ListPushed(app, event)
            [List_File,List_Path] = uigetfile('*.txt','Select a .txt list of Patient IDs');
            List=strcat(List_Path,List_File); 
            app.PatientListtxtEditField.Value=List; 
        end

        % Button pushed function: Browse_Button_Lithium_Img
        function Browse_Button_Lithium_ImgPushed(app, event)
            [LithImg_File,LithImg_Path] = uigetfile('*.txt','Select a .txt of Lithium Filepaths');
            Lithium_Img_List=strcat(LithImg_Path,LithImg_File); 
            app.LithiumImgtxtEditField.Value=Lithium_Img_List; 
        end

        % Button pushed function: Browse_Button_Lithium_T1w
        function Browse_Button_Lithium_T1wPushed(app, event)
            [LithT1_File,LithT1_Path] = uigetfile('*.txt','Select a .txt of Lithium T1w Filepaths');
            Lithium_T1_List=strcat(LithT1_Path,LithT1_File); 
            app.LithiumT1wtxtEditField.Value=Lithium_T1_List; 
        end

        % Button pushed function: Browse_Button_FS_Folder
        function Browse_Button_FS_FolderPushed(app, event)
            [FS_Folder_File,FS_Folder_Path] = uigetfile('*.txt','Select a .txt of FreeSurfer Filepaths');
            FS_Folder_List=strcat(FS_Folder_Path,FS_Folder_File); 
            app.FSFoldertxtEditField.Value=FS_Folder_List; 
        end

        % Button pushed function: BrowseButton_Output
        function BrowseButton_OutputPushed(app, event)
            Output_Folder = uigetdir('Select the location and name of the output');
            Overall_Output_Folder=Output_Folder; 
            app.OutputFolderEditField.Value=Overall_Output_Folder; 
        end

        % Button pushed function: Browse_Button_DCI2NII_Folder
        function Browse_Button_DCI2NII_FolderPushed(app, event)
            DCI2NII_Folder = uigetdir('Select the location of the xiangruili-dicm2nii-3fe1a27 folder on your PC');
            app.Pathwaytodicm2niifolderEditField.Value=DCI2NII_Folder; 
        end

        % Value changed function: NoLithiumT1AcquiredCheckBox
        function NoLithiumT1AcquiredCheckBoxValueChanged(app, event)
        % Keeping this open for now
        end

        % Button pushed function: RunPipelineButton
        function RunPipelineButtonPushed(app, event)
            
            %Addpath
            DCI2NII_Folder=app.Pathwaytodicm2niifolderEditField.Value;
            addpath(DCI2NII_Folder)
            %Check if No Lithium has been checked
            NoLithiumT1 = app.NoLithiumT1AcquiredCheckBox.Value;
            
            % If has
            if NoLithiumT1 == 1 
    
                List=app.PatientListtxtEditField.Value; 
                Lithium_Img_List=app.LithiumImgtxtEditField.Value; 
                FS_Folder_List=app.FSFoldertxtEditField.Value; 
                Overall_Output_Dir=app.OutputFolderEditField.Value; 
                
                Lithium_Pipeline_Main_NoT1w(List,Lithium_Img_List,FS_Folder_List,Overall_Output_Dir,DCI2NII_Folder)
            end 


            %Is hasn't
            if NoLithiumT1 == 0
                List=app.PatientListtxtEditField.Value; 
                Lithium_Img_List=app.LithiumImgtxtEditField.Value; 
                Lithium_T1_List=app.LithiumT1wtxtEditField.Value; 
                FS_Folder_List=app.FSFoldertxtEditField.Value; 
                Overall_Output_Dir=app.OutputFolderEditField.Value; 
                
                Lithium_Pipeline_Main(List,Lithium_Img_List,Lithium_T1_List,FS_Folder_List,Overall_Output_Dir,DCI2NII_Folder)
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientListtxtEditFieldLabel = uilabel(app.UIFigure);
            app.PatientListtxtEditFieldLabel.Text = 'Patient List (.txt)';
            app.LithiumAnalyzerLabel = uilabel(app.UIFigure);
            app.LithiumAnalyzerLabel.Text = 'Lithium Analyzer';
            app.LithiumImgtxtEditFieldLabel = uilabel(app.UIFigure);
            app.LithiumImgtxtEditFieldLabel.Text = 'Lithium Img (.txt)';
            app.LithiumImgtxtEditField = uieditfield(app.UIFigure, 'text');
            app.FilepathsLabel = uilabel(app.UIFigure);
            app.FilepathsLabel.Text = 'Filepaths';
            app.LithiumT1wtxtEditFieldLabel = uilabel(app.UIFigure);
            app.LithiumT1wtxtEditFieldLabel.Text = 'Lithium T1w (.txt)';
            app.LithiumT1wtxtEditField = uieditfield(app.UIFigure, 'text');
            app.FSFoldertxtEditFieldLabel = uilabel(app.UIFigure);
            app.FSFoldertxtEditFieldLabel.Text = 'FS Folder (.txt)';
            app.FSFoldertxtEditField = uieditfield(app.UIFigure, 'text');
            app.RunPipelineButton = uibutton(app.UIFigure, 'push');
            app.RunPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunPipelineButtonPushed, true);
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.OutputFolderEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.UIFigure, 'text');
            app.NoLithiumT1AcquiredCheckBox = uicheckbox(app.UIFigure);
            app.NoLithiumT1AcquiredCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoLithiumT1AcquiredCheckBoxValueChanged, true);
            app.NoLithiumT1AcquiredCheckBox.Text = 'No Lithium T1 Acquired';
            app.PatientListtxtEditField = uieditfield(app.UIFigure, 'text');
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://www.cnnp-lab.com/home';
            app.Hyperlink.Text = 'CNNP Lab';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'CNNP_Logo.png';
            app.QuestionsandhelppleaseemailgerardhallnewcastleacukLabel = uilabel(app.UIFigure);
            app.QuestionsandhelppleaseemailgerardhallnewcastleacukLabel.Text = {'Questions and help please email:'; 'gerard.hall@newcastle.ac.uk'};
            app.Browse_Button_Patient_List = uibutton(app.UIFigure, 'push');
            app.Browse_Button_Patient_List.ButtonPushedFcn = createCallbackFcn(app, @Browse_Button_Patient_ListPushed, true);
            app.Browse_Button_Patient_List.Text = 'Browse';
            app.Browse_Button_Lithium_Img = uibutton(app.UIFigure, 'push');
            app.Browse_Button_Lithium_Img.ButtonPushedFcn = createCallbackFcn(app, @Browse_Button_Lithium_ImgPushed, true);
            app.Browse_Button_Lithium_Img.Text = 'Browse';
            app.Browse_Button_Lithium_T1w = uibutton(app.UIFigure, 'push');
            app.Browse_Button_Lithium_T1w.ButtonPushedFcn = createCallbackFcn(app, @Browse_Button_Lithium_T1wPushed, true);
            app.Browse_Button_Lithium_T1w.Text = 'Browse';
            app.Browse_Button_FS_Folder = uibutton(app.UIFigure, 'push');
            app.Browse_Button_FS_Folder.ButtonPushedFcn = createCallbackFcn(app, @Browse_Button_FS_FolderPushed, true);
            app.Browse_Button_FS_Folder.Text = 'Browse';
            app.BrowseButton_Output = uibutton(app.UIFigure, 'push');
            app.BrowseButton_Output.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_OutputPushed, true);
            app.BrowseButton_Output.Text = 'Browse';
            app.Pathwaytodicm2niifolderEditFieldLabel = uilabel(app.UIFigure);
            app.Pathwaytodicm2niifolderEditFieldLabel.Text = 'Pathway to dicm2nii folder';
            app.Pathwaytodicm2niifolderEditField = uieditfield(app.UIFigure, 'text');
            app.Browse_Button_DCI2NII_Folder = uibutton(app.UIFigure, 'push');
            app.Browse_Button_DCI2NII_Folder.ButtonPushedFcn = createCallbackFcn(app, @Browse_Button_DCI2NII_FolderPushed, true);
            app.Browse_Button_DCI2NII_Folder.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lithium_Pipeline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end